classdef OHCmanual < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OHCcorrection      matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        UIAxes2            matlab.ui.control.UIAxes
        ZlayerSliderLabel  matlab.ui.control.Label
        ZlayerSlider       matlab.ui.control.Slider
        SaveButton         matlab.ui.control.Button
        Label              matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp % Main app object
        IMGdata % recorded data
        cellCentroids % centroids
        maxPro % maximum projection centroids (on UIAxes)
        singleLay % single layer centroids (on UIAxes2)
        originalCellNum
        a
        b
    end    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, data, centroids)
            app.CallingApp = mainapp;
            app.IMGdata = data;
            centroids = [centroids ones(size(centroids,1),1)];
            app.cellCentroids = centroids;
            app.originalCellNum = size(centroids,1);
                
            app.ZlayerSlider.Limits = [1 size(data,3)];
            app.ZlayerSlider.MinorTicks = [];
            app.UIAxes.XLim = [1 size(data,4)];
            app.UIAxes.YLim = [1 size(data,5)];
            app.UIAxes2.XLim = [1 size(data,4)];
            app.UIAxes2.YLim = [1 size(data,5)];
            
            imagesc(app.UIAxes, reshape(max(reshape(data(1,1,:,:,:), [size(data,3) size(data,4) size(data,5)]),[],1), [size(data,4) size(data,5)]));
            for k = 1:size(centroids,1)
                app.maxPro(k) = line(app.UIAxes, centroids(k,1), centroids(k,2), 'Marker', 'o', 'Color', 'g','MarkerSize', 10, 'LineWidth', 2, 'UserData', k);
            end
            
            imagesc(app.UIAxes2,reshape(data(1,1,1,:,:), [size(data,4) size(data,5)]),'ButtonDownFcn', {@addCell, 1, app});
            for k = 1:size(centroids,1)
                if centroids(k,3) == 1
                    app.singleLay(k) = line(app.UIAxes2, centroids(k,1), centroids(k,2), 'Marker', 'o', 'Color', 'g','MarkerSize', 10, 'LineWidth', 2, 'UserData', k , 'ButtonDownFcn', {@disappearCell, app});
                end
            end
            
            app.a = app.UIAxes.XLim;
            app.b = app.UIAxes2.XLim;
        end

        % Key press function: OHCcorrection
        function OHCcorrectionKeyPress(app, event)
            key = event.Key;
            
            if strcmp(key,'control')
                if app.UIAxes.XLim ~= app.a
                    app.UIAxes2.XLim = app.UIAxes.XLim;
                    app.UIAxes2.YLim = app.UIAxes.YLim;
                    app.a = app.UIAxes.XLim;
                elseif app.UIAxes2.XLim ~=app.b
                    app.UIAxes.XLim = app.UIAxes2.XLim;
                    app.UIAxes.YLim = app.UIAxes2.YLim;
                    app.b = app.UIAxes2.XLim;
                end
            end
            
            if strcmp(key, 'rightarrow') && app.ZlayerSlider.Value < size(app.IMGdata,3)
                app.ZlayerSlider.Value = app.ZlayerSlider.Value + 1;
                ZlayerSliderValueChanged(app, event);
            end
            if strcmp(key, 'leftarrow') && app.ZlayerSlider.Value > 1
                app.ZlayerSlider.Value = app.ZlayerSlider.Value - 1;
                ZlayerSliderValueChanged(app, event);
            end
        end

        % Value changed function: ZlayerSlider
        function ZlayerSliderValueChanged(app, event)
            value = round(app.ZlayerSlider.Value);
            app.ZlayerSlider.Value = value;
            app.Label.Text = num2str(value);
            X = app.UIAxes2.XLim;
            Y = app.UIAxes2.YLim;

            cla(app.UIAxes2);
            app.UIAxes2.XLim = X;
            app.UIAxes2.YLim = Y;
            
            imagesc(app.UIAxes2,reshape(app.IMGdata(1,1,value,:,:), [size(app.IMGdata,4) size(app.IMGdata,5)]),'ButtonDownFcn', {@addCell, value, app});
            for k = 1:size(app.cellCentroids,1)
                if app.cellCentroids(k,3) == value
                    if k <= app.originalCellNum
                        if app.cellCentroids(k,end) == 0
                            app.singleLay(k) = line(app.UIAxes2, app.cellCentroids(k,1), app.cellCentroids(k,2), 'Marker', 'o', 'Color', 'r','MarkerSize', 10, 'LineWidth', 2, 'UserData', k , 'ButtonDownFcn', {@disappearCell, app});
                        else
                            app.singleLay(k) = line(app.UIAxes2, app.cellCentroids(k,1), app.cellCentroids(k,2), 'Marker', 'o', 'Color', 'g','MarkerSize', 10, 'LineWidth', 2, 'UserData', k , 'ButtonDownFcn', {@disappearCell, app});
                        end
                    else
                        if app.cellCentroids(k,end) == 1
                            app.singleLay(k) = line(app.UIAxes2, app.cellCentroids(k,1), app.cellCentroids(k,2), 'Marker', 'o', 'Color', 'k','MarkerSize', 10, 'LineWidth', 2, 'UserData', k , 'ButtonDownFcn', {@disappearAddedCell, app});
                        end
                    end
                end
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            newCells = [];
            for i = 1:size(app.cellCentroids,1)
                if app.cellCentroids(i,end)
                    newCells = [newCells; app.cellCentroids(i,1:end-1)];
                end
            end
            assignin('base','OHC',newCells);
            app.CallingApp.OHCButton.UserData = newCells;
            
            OHC = newCells;
            fn = [app.CallingApp.ChoosefolderButton.UserData '\countRes\' app.CallingApp.OpenimageButton.UserData];
            [filepath,name,~] = fileparts(fn);
            save([filepath '\' name '_OHC.mat'], 'OHC');
            
            disp('Outer hair cells saved!');
        end

        % Close request function: OHCcorrection
        function OHCcorrectionCloseRequest(app, event)
            selection = questdlg('Close window?', 'Warning', 'Yes', 'No', 'Yes');
            switch selection
                case 'Yes'
                    delete(app)
                case 'No'
                    return
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OHCcorrection = uifigure;
            app.OHCcorrection.Name = 'Manual OHC';
            app.OHCcorrection.CloseRequestFcn = createCallbackFcn(app, @OHCcorrectionCloseRequest, true);
            app.OHCcorrection.KeyPressFcn = createCallbackFcn(app, @OHCcorrectionKeyPress, true);
            app.UIAxes = uiaxes(app.OHCcorrection);
            title(app.UIAxes, 'Maximum projection (Blue)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.OHCcorrection);
            title(app.UIAxes2, 'Blue')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.ZlayerSliderLabel = uilabel(app.OHCcorrection);
            app.ZlayerSliderLabel.Text = 'Z layer';
            app.ZlayerSlider = uislider(app.OHCcorrection);
            app.ZlayerSlider.Limits = [1 100];
            app.ZlayerSlider.ValueChangedFcn = createCallbackFcn(app, @ZlayerSliderValueChanged, true);
            app.ZlayerSlider.Value = 1;
            app.SaveButton = uibutton(app.OHCcorrection, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Label = uilabel(app.OHCcorrection);
            app.Label.Text = '1';
        end
    end

    methods (Access = public)

        % Construct app
        function app = OHCmanual(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OHCcorrection)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OHCcorrection)
        end
    end
end