classdef SynCounter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SynCounterUIFigure  matlab.ui.Figure
        ListBox             matlab.ui.control.ListBox
        OHCButton           matlab.ui.control.Button
        IHCButton           matlab.ui.control.Button
        SynapseButton       matlab.ui.control.Button
        ChoosefolderButton  matlab.ui.control.Button
        OHCCheckBox         matlab.ui.control.CheckBox
        IHCCheckBox         matlab.ui.control.CheckBox
        SynapseCheckBox     matlab.ui.control.CheckBox
        ButtonGroup         matlab.ui.container.ButtonGroup
        SingleButton        matlab.ui.control.RadioButton
        NODATAButton        matlab.ui.control.RadioButton
        AllButton           matlab.ui.control.RadioButton
        RunButton           matlab.ui.control.Button
        SelectedfileLabel   matlab.ui.control.Label
        OpenimageButton     matlab.ui.control.Button
    end

    
    properties (Access = public)
        DialogApp % Description
    end
    

    methods (Access = private)

        % Button pushed function: ChoosefolderButton
        function ChoosefolderButtonPushed(app, event)
            selpath = uigetdir;
            if selpath ~= 0
                fileList = [dir([selpath '\*.czi']); dir([selpath '\*.lsm'])];
                filesCell = cell(size(fileList,1),1,1);
                for i = 1:size(fileList,1)
                    name = [fileList(i).folder '\countRes\' fileList(i).name(1:end-4)];
                    if isfile([name '.mat']) || isfile([name '_OHC.mat']) || isfile([name '_IHC.mat']) || isfile([name '_Synapse.mat'])
                        filesCell{i,1,1} = fileList(i).name;
                    else
                        filesCell{i,1,1} = ['[NO DATA] ' fileList(i).name];
                    end
                end
                set(app.ListBox, 'Items', filesCell);
                set(app.ChoosefolderButton, 'UserData', selpath);
            end
        end

        % Button pushed function: OpenimageButton
        function OpenimageButtonPushed(app, event)
            file_selected = app.ListBox.Value;
            if isempty(file_selected)
                return;
            end                
            app.OpenimageButton.UserData = file_selected;
            set(app.OHCButton, 'Enable', 'off');
            set(app.IHCButton, 'Enable', 'off');
            set(app.SynapseButton, 'Enable', 'off');
            
            selpath = app.ChoosefolderButton.UserData;
         
            matFile = [selpath '\countRes\' file_selected(1:end-4)];
            set(app.SelectedfileLabel,'Text',[selpath '\' file_selected]);
            
            if ~strcmp(file_selected(1:9), '[NO DATA]')
                if isequal(file_selected(end-2:end),'lsm')
                    [data, ~] = lsmread([selpath '\' file_selected]);
                    assignin('base','data',data);
                else
                    [data, ~]  = changeCZIdata([selpath '\' file_selected]);
                    assignin('base','data',data);
                end
                app.ListBox.UserData = data;
                if isfile([matFile '_OHC.mat'])
                    var = load([matFile '_OHC.mat']);
                    OHC = var.OHC;
                    assignin('base','OHC',OHC);
                    app.OHCButton.UserData = OHC;
                    set(app.OHCButton, 'Enable', 'on');
                end
                if isfile([matFile '_IHC.mat'])
                    var = load([matFile '_IHC.mat']);
                    IHC = var.IHC;
                    assignin('base','IHC',IHC);
                    app.IHCButton.UserData = IHC;
                    set(app.IHCButton, 'Enable', 'on');
                end
                if isfile([matFile '_Synapse.mat'])
                    var = load([matFile '_Synapse.mat']);
                    pairs = var.pairs;
                    assignin('base','pairs',pairs);
                    app.SynapseButton.UserData = pairs;
                    set(app.SynapseButton, 'Enable', 'on');
                end
                disp('File is read!');
            end  
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)

            selpath = app.ChoosefolderButton.UserData;
            rb =get(app.ButtonGroup, 'Children');
            
            for i = 1:length(rb)
                if get(rb(i),'Value') == 1
                    name = get(rb(i),'Text');
                end
            end
            filesCell = get(app.ListBox, 'Items');
            selectedFiles = {};
            switch name
                case 'All'
                    for i = 1:length(filesCell)
                        if strcmp(filesCell{i}(1:9),'[NO DATA]')
                            selectedFiles{length(selectedFiles) + 1} = [selpath '\' filesCell{i}(11:end)];
                        else
                            selectedFiles{length(selectedFiles) + 1} = [selpath '\' filesCell{i}];
                        end
                    end
                case '[NO DATA]'
                    for i = 1:length(filesCell)
                        if strcmp(filesCell{i}(1:9),'[NO DATA]')
                            selectedFiles{length(selectedFiles) + 1} = [selpath '\' filesCell{i}(11:end)];
                        end
                    end
                case 'Single'
                    file_selected = get(app.ListBox, 'Value');
                    if ~isempty(filesCell)
                        if strcmp(file_selected(1:9),'[NO DATA]')
                            selectedFiles{length(selectedFiles) + 1} = [selpath '\' file_selected(11:end)];
                        else
                            selectedFiles{length(selectedFiles) + 1} = [selpath '\' file_selected];
                        end
                    end
            end
            
            assignin('base','selectedFiles',selectedFiles)
            tic
            disp('Running...');
            counters = [get(app.OHCCheckBox,'Value'); get(app.IHCCheckBox,'Value'); get(app.SynapseCheckBox,'Value')];
            for f = 1:length(selectedFiles)
                fn = selectedFiles{f};
                if sum(counters) > 0
                    [filepath,name,ext] = fileparts(fn);
                    disp([num2str(f) '/' num2str(length(selectedFiles)) ' .. ' ...
                        name]);
                    if isequal(fn(end-2:end),'lsm')
                        [data, info] = lsmread(fn);
                        Zdist = info.voxSizeZ*1e6;
                        Xdist = info.voxSizeX*1e6;
                        if size(data,3) == 4
                            errEv = 0;
                        elseif size(data,3) == 3
                            errEv = 2;
                        else
                            errEv = 1;
                        end
                    else
                        [data, Xdist, Zdist, errEv]  = changeCZIdata(fn);
                    end
                    switch errEv
                        case 1
                            warning('Number of channels (<3) is not enough. The recording will not be processed.');
                            continue
                        case 2
                            if counters(1)
                                warning('Number of channels is 3. OHC cannot be processed!')
                            end
                    end
                    
                    valRed = [];
                    if counters(3)
                        [pairs, valRed] = testSynapse([filepath '\'], [name ext], data, Zdist);
                        if exist([filepath '\countRes'],'dir')
                            save([filepath '\countRes\' name '_Synapse.mat'],'pairs');
                        else
                            mkdir([filepath '\countRes']);
                            save([filepath '\countRes\' name '_Synapse.mat'],'pairs');
                        end
                    end
                    if counters(2)
                        if ~counters(3)
                            [~, valRed] = testSynapse([filepath '\'], [name ext], data, Zdist);
                        end
                        IHC = IHCcount([filepath '\'], [name ext], valRed, data, Xdist, Zdist);
                        if exist([filepath '\countRes'],'dir')
                            save([filepath '\countRes\' name '_IHC.mat'],'IHC');
                        else
                            mkdir([filepath '\countRes']);
                            save([filepath '\countRes\' name '_IHC.mat'],'IHC');
                        end
                    end
                    if counters(1) && errEv ~= 2
                        OHC = OHCcount([filepath '\'], [name ext], data, Xdist, Zdist);
                        if exist([filepath '\countRes'],'dir')
                            save([filepath '\countRes\' name '_OHC.mat'],'OHC');
                        else
                            mkdir([filepath '\countRes']);
                            save([filepath '\countRes\' name '_OHC.mat'],'OHC');
                        end
                    end
                end
            end
            fileList = [dir([selpath '\*.czi']); dir([selpath '\*.lsm'])];
            filesCell = cell(size(fileList,1),1,1);
            for i = 1:size(fileList,1)
                name = [fileList(i).folder '\countRes\' fileList(i).name(1:end-4)];
                if isfile([name '.mat']) || isfile([name '_OHC.mat']) || isfile([name '_IHC.mat']) || isfile([name '_Synapse.mat'])
                    filesCell{i,1,1} = fileList(i).name;
                else
                    filesCell{i,1,1} = ['[NO DATA] ' fileList(i).name];
                end
            end
            disp('Finished!');
            toc
            set(app.ListBox, 'Items', filesCell);
        end

        % Button pushed function: OHCButton
        function OHCButtonPushed(app, event)
            app.DialogApp{length(app.DialogApp)+1} = OHCmanual(app,app.ListBox.UserData,app.OHCButton.UserData);
        end

        % Button pushed function: IHCButton
        function IHCButtonPushed(app, event)
            app.DialogApp{length(app.DialogApp)+1} = IHCmanual(app,app.ListBox.UserData,app.IHCButton.UserData);
        end

        % Button pushed function: SynapseButton
        function SynapseButtonPushed(app, event)
            app.DialogApp{length(app.DialogApp)+1} = Synapsemanual(app,app.ListBox.UserData,app.SynapseButton.UserData);
        end

        % Close request function: SynCounterUIFigure
        function SynCounterUIFigureCloseRequest(app, event)
            for i = 1:length(app.DialogApp)
                delete(app.DialogApp{i})
            end
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SynCounterUIFigure = uifigure;
            app.SynCounterUIFigure.Name = 'SynCounter';
            app.SynCounterUIFigure.CloseRequestFcn = createCallbackFcn(app, @SynCounterUIFigureCloseRequest, true);
            app.ListBox = uilistbox(app.SynCounterUIFigure);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.OHCButton = uibutton(app.SynCounterUIFigure, 'push');
            app.OHCButton.ButtonPushedFcn = createCallbackFcn(app, @OHCButtonPushed, true);
            app.OHCButton.Enable = 'off';
            app.OHCButton.Text = 'OHC';
            app.IHCButton = uibutton(app.SynCounterUIFigure, 'push');
            app.IHCButton.ButtonPushedFcn = createCallbackFcn(app, @IHCButtonPushed, true);
            app.IHCButton.Enable = 'off';
            app.IHCButton.Text = 'IHC';
            app.SynapseButton = uibutton(app.SynCounterUIFigure, 'push');
            app.SynapseButton.ButtonPushedFcn = createCallbackFcn(app, @SynapseButtonPushed, true);
            app.SynapseButton.Enable = 'off';
            app.SynapseButton.Text = 'Synapse';
            app.ChoosefolderButton = uibutton(app.SynCounterUIFigure, 'push');
            app.ChoosefolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefolderButtonPushed, true);
            app.ChoosefolderButton.Text = 'Choose folder';
            app.OHCCheckBox = uicheckbox(app.SynCounterUIFigure);
            app.OHCCheckBox.Text = 'OHC';
            app.IHCCheckBox = uicheckbox(app.SynCounterUIFigure);
            app.IHCCheckBox.Text = 'IHC';
            app.SynapseCheckBox = uicheckbox(app.SynCounterUIFigure);
            app.SynapseCheckBox.Text = 'Synapse';
            app.ButtonGroup = uibuttongroup(app.SynCounterUIFigure);
            app.SingleButton = uiradiobutton(app.ButtonGroup);
            app.SingleButton.Text = 'Single';
            app.SingleButton.Value = true;
            app.NODATAButton = uiradiobutton(app.ButtonGroup);
            app.NODATAButton.Text = '[NO DATA]';
            app.AllButton = uiradiobutton(app.ButtonGroup);
            app.AllButton.Text = 'All';
            app.RunButton = uibutton(app.SynCounterUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SelectedfileLabel = uilabel(app.SynCounterUIFigure);
            app.SelectedfileLabel.Text = 'Selected file';
            app.OpenimageButton = uibutton(app.SynCounterUIFigure, 'push');
            app.OpenimageButton.ButtonPushedFcn = createCallbackFcn(app, @OpenimageButtonPushed, true);
            app.OpenimageButton.Text = 'Open image';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SynCounter

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SynCounterUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SynCounterUIFigure)
        end
    end
end