classdef Synapsemanual < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Synapsecorrection  matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        UIAxes2            matlab.ui.control.UIAxes
        ZlayerSliderLabel  matlab.ui.control.Label
        ZlayerSlider       matlab.ui.control.Slider
        SaveButton         matlab.ui.control.Button
        Label              matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp % Main app object
        IMGdata % recorded data
        synCentroids % centroids
        preSyn % presynaptic terminal (on UIAxes)
        postSyn % postsynaptic terminal (on UIAxes2)
        originalSynNum
        a
        b
    end    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, data, centroids)
            app.CallingApp = mainapp;
            app.IMGdata = data;
            centroids = [centroids ones(size(centroids,1),1)];
            app.synCentroids = centroids;
            app.originalSynNum = size(centroids,1);
                
            app.ZlayerSlider.Limits = [1 size(data,3)];
            app.ZlayerSlider.MinorTicks = [];
            app.UIAxes.XLim = [1 size(data,4)];
            app.UIAxes.YLim = [1 size(data,5)];
            app.UIAxes2.XLim = [1 size(data,4)];
            app.UIAxes2.YLim = [1 size(data,5)];
            
            imagesc(app.UIAxes, reshape(data(1,3,1,:,:), [size(data,4) size(data,5)]));
            for k = 1:size(centroids,1)
                if centroids(k,1) < 2
                    app.preSyn(k) = line(app.UIAxes, centroids(k,3), centroids(k,2), 'Marker', 'o', 'Color', 'g','MarkerSize', 5, 'LineWidth', 1, 'UserData', k);
                end
            end
            
            imagesc(app.UIAxes2,reshape(data(1,2,1,:,:), [size(data,4) size(data,5)]),'ButtonDownFcn', {@addPair, 1, app});
            for k = 1:size(centroids,1)
                if centroids(k,1) < 2
                    app.postSyn(k) = line(app.UIAxes2, centroids(k,3), centroids(k,2), 'Marker', 'o', 'Color', 'g','MarkerSize', 5, 'LineWidth', 1, 'UserData', k , 'ButtonDownFcn', {@disappearPair, app});
                end
            end
            
            app.a = app.UIAxes.XLim;
            app.b = app.UIAxes2.XLim;
        end

        % Key press function: Synapsecorrection
        function SynapsecorrectionKeyPress(app, event)
            key = event.Key;
            
            if strcmp(key,'control')
                if app.UIAxes.XLim ~= app.a
                    app.UIAxes2.XLim = app.UIAxes.XLim;
                    app.UIAxes2.YLim = app.UIAxes.YLim;
                    app.a = app.UIAxes.XLim;
                elseif app.UIAxes2.XLim ~=app.b
                    app.UIAxes.XLim = app.UIAxes2.XLim;
                    app.UIAxes.YLim = app.UIAxes2.YLim;
                    app.b = app.UIAxes2.XLim;
                end
            end
            
            if strcmp(key, 'rightarrow') && app.ZlayerSlider.Value < size(app.IMGdata,3)
                app.ZlayerSlider.Value = app.ZlayerSlider.Value + 1;
                ZlayerSliderValueChanged(app, event);
            end
            if strcmp(key, 'leftarrow') && app.ZlayerSlider.Value > 1
                app.ZlayerSlider.Value = app.ZlayerSlider.Value - 1;
                ZlayerSliderValueChanged(app, event);
            end
        end

        % Value changed function: ZlayerSlider
        function ZlayerSliderValueChanged(app, event)
            value = round(app.ZlayerSlider.Value);
            app.ZlayerSlider.Value = value;
            app.Label.Text = num2str(value);
            
            X = app.UIAxes.XLim;
            Y = app.UIAxes.YLim;

            cla(app.UIAxes);
            app.UIAxes.XLim = X;
            app.UIAxes.YLim = Y;
            
            imagesc(app.UIAxes,reshape(app.IMGdata(1,3,value,:,:), [size(app.IMGdata,4) size(app.IMGdata,5)]));
            for k = 1:size(app.synCentroids,1)
                if app.synCentroids(k,1) > value - 1 && app.synCentroids(k,1) < value + 1
                    if k <= app.originalSynNum
                        if app.synCentroids(k,end) == 0
                            app.preSyn(k) = line(app.UIAxes, app.synCentroids(k,3), app.synCentroids(k,2), 'Marker', 'o', 'Color', 'r','MarkerSize', 5, 'LineWidth', 1, 'UserData', k);
                        else
                            app.preSyn(k) = line(app.UIAxes, app.synCentroids(k,3), app.synCentroids(k,2), 'Marker', 'o', 'Color', 'g','MarkerSize', 5, 'LineWidth', 1, 'UserData', k);
                        end
                    else
                        if app.synCentroids(k,end) == 1
                            app.preSyn(k) = line(app.UIAxes, app.synCentroids(k,3), app.synCentroids(k,2), 'Marker', 'o', 'Color', 'k','MarkerSize', 5, 'LineWidth', 1, 'UserData', k);
                        end
                    end
                end
            end
            
            X = app.UIAxes2.XLim;
            Y = app.UIAxes2.YLim;

            cla(app.UIAxes2);
            app.UIAxes2.XLim = X;
            app.UIAxes2.YLim = Y;
            
            imagesc(app.UIAxes2,reshape(app.IMGdata(1,2,value,:,:), [size(app.IMGdata,4) size(app.IMGdata,5)]),'ButtonDownFcn', {@addPair, value, app});
            for k = 1:size(app.synCentroids,1)
                if app.synCentroids(k,1) > value - 1 && app.synCentroids(k,1) < value + 1
                    if k <= app.originalSynNum
                        if app.synCentroids(k,end) == 0
                            app.postSyn(k) = line(app.UIAxes2, app.synCentroids(k,3), app.synCentroids(k,2), 'Marker', 'o', 'Color', 'r','MarkerSize', 5, 'LineWidth', 1, 'UserData', k , 'ButtonDownFcn', {@disappearPair, app});
                        else
                            app.postSyn(k) = line(app.UIAxes2, app.synCentroids(k,3), app.synCentroids(k,2), 'Marker', 'o', 'Color', 'g','MarkerSize', 5, 'LineWidth', 1, 'UserData', k , 'ButtonDownFcn', {@disappearPair, app});
                        end
                    else
                        if app.synCentroids(k,end) == 1
                            app.postSyn(k) = line(app.UIAxes2, app.synCentroids(k,3), app.synCentroids(k,2), 'Marker', 'o', 'Color', 'k','MarkerSize', 5, 'LineWidth', 1, 'UserData', k , 'ButtonDownFcn', {@disappearAddedPair, app});
                        end
                    end
                end
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            newPairs = [];
            for i = 1:size(app.synCentroids,1)
                if app.synCentroids(i,end)
                    newPairs = [newPairs; app.synCentroids(i,1:end-1)];
                end
            end
            assignin('base','pairs',newPairs);
            app.CallingApp.SynapseButton.UserData = newPairs;
            
            pairs = newPairs;
            fn = [app.CallingApp.ChoosefolderButton.UserData '\countRes\' app.CallingApp.OpenimageButton.UserData];
            [filepath,name,~] = fileparts(fn);
            save([filepath '\' name '_Synapse.mat'], 'pairs');
            
            disp('Synapses saved!');
        end

        % Close request function: Synapsecorrection
        function SynapsecorrectionCloseRequest(app, event)
            selection = questdlg('Close window?', 'Warning', 'Yes', 'No', 'Yes');
            switch selection
                case 'Yes'
                    delete(app)
                case 'No'
                    return
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Synapsecorrection = uifigure;
            app.Synapsecorrection.Name = 'Manual Synapse';
            app.Synapsecorrection.CloseRequestFcn = createCallbackFcn(app, @SynapsecorrectionCloseRequest, true);
            app.Synapsecorrection.KeyPressFcn = createCallbackFcn(app, @SynapsecorrectionKeyPress, true);
            app.UIAxes = uiaxes(app.Synapsecorrection);
            title(app.UIAxes, 'Red (presynaptic)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.Synapsecorrection);
            title(app.UIAxes2, 'Green (postsynaptic)')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.ZlayerSliderLabel = uilabel(app.Synapsecorrection);
            app.ZlayerSliderLabel.Text = 'Z layer';
            app.ZlayerSlider = uislider(app.Synapsecorrection);
            app.ZlayerSlider.Limits = [1 100];
            app.ZlayerSlider.ValueChangedFcn = createCallbackFcn(app, @ZlayerSliderValueChanged, true);
            app.ZlayerSlider.Value = 1;
            app.SaveButton = uibutton(app.Synapsecorrection, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Label = uilabel(app.Synapsecorrection);
            app.Label.Text = '1';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Synapsemanual(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Synapsecorrection)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Synapsecorrection)
        end
    end
end